classdef v_fft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Toolbar      matlab.ui.container.Toolbar
        guardar_fft  matlab.ui.container.toolbar.PushTool
        Grafica2     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, aux, aux2, v_frecuencia, v_fft, v_frecuencia1, v_fft1)
        color1=[1 0 1];
        color2=[0 0.45 0.74];
            if aux==1
                stem(app.Grafica2,v_frecuencia,v_fft,'filled','.','Color',color1)
            elseif aux2==1 && aux==0
              hold(app.Grafica2, 'on')  
                stem(app.Grafica2,v_frecuencia,v_fft,'filled','.','Color',color1)
                stem(app.Grafica2,v_frecuencia1,v_fft1,'filled','.','Color',color2)
              hold(app.Grafica2, 'off')  
            elseif aux2==0 && aux==0
                stem(app.Grafica2,v_frecuencia1,v_fft1,'filled','.','Color',color1)
            end
        end

        % Clicked callback: guardar_fft
        function guardar_fftClicked(app, event)
            saveas(app.Grafica2,uiputfile({'*.fig'}))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.guardar_fft = uipushtool(app.Toolbar);
            app.guardar_fft.ClickedCallback = createCallbackFcn(app, @guardar_fftClicked, true);
            app.guardar_fft.Icon = 'save.png';
            app.Grafica2 = uiaxes(app.UIFigure);
            title(app.Grafica2, 'FFT SEÑAL ECG')
            xlabel(app.Grafica2, 'Frecuencia (Hz)')
            ylabel(app.Grafica2, 'Amplitud')
            zlabel(app.Grafica2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v_fft(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end