classdef v_historial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Toolbar            matlab.ui.container.Toolbar
        guardar_historial  matlab.ui.container.toolbar.PushTool
        HISTORIALPanel     matlab.ui.container.Panel
        IRRButton          matlab.ui.control.Button
        OPButton           matlab.ui.control.Button
        ITButton           matlab.ui.control.Button
        ISTButton          matlab.ui.control.Button
        SSTButton          matlab.ui.control.Button
        IPRButton          matlab.ui.control.Button
        IQRSButton         matlab.ui.control.Button
        IQTButton          matlab.ui.control.Button
        SPRButton          matlab.ui.control.Button
        OTButton           matlab.ui.control.Button
        Button             matlab.ui.control.Button
        tabla              matlab.ui.control.Table
    end

    
    properties (Access = public)
            rr
            it
            st
            sst
            pr
            spr
            qrs
            qt
            t
            p
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datosRR, datosIT, datosST, datosSST, datosPR, datosSPR, datosT, datosP, datosQRS, datosQT)
            %Se realiza una serie de tares para poder ver la tabla de datos visible asi como su formato (columnas, filas). Dependiendo de
            %cada botón accionado se asigna los datos almacenados a la variable de salida.
            app.tabla.Visible='on';
            app.tabla.Enable='on';
            app.tabla.ColumnFormat={'numeric','numeric','numeric'};
            app.rr=datosRR;
            app.it=datosIT;
            app.st=datosST;
            app.sst=datosSST;
            app.pr=datosPR;
            app.spr=datosSPR;
            app.qrs=datosQRS;
            app.qt=datosQT;
            app.t=datosT;
            app.p=datosP;
        end

        % Button pushed function: IRRButton
        function IRRButtonPushed(app, event)
            app.tabla.ColumnName={'T','T-1','INTERVALO'};
            app.tabla.Data=app.rr;               
        end

        % Button pushed function: ITButton
        function ITButtonPushed(app, event)
            app.tabla.ColumnName={'T-1','T','INTERVALO'};
            app.tabla.Data=app.it;
        end

        % Button pushed function: ISTButton
        function ISTButtonPushed(app, event)
            app.tabla.ColumnName={'T-1','T','INTERVALO'};
            app.tabla.Data=app.st;
        end

        % Button pushed function: SSTButton
        function SSTButtonPushed(app, event)
            app.tabla.ColumnName={'T-1','T','INTERVALO'};
            app.tabla.Data=app.sst;
        end

        % Button pushed function: IPRButton
        function IPRButtonPushed(app, event)
            app.tabla.ColumnName={'T-1','T','INTERVALO'};
            app.tabla.Data=app.pr;
        end

        % Button pushed function: SPRButton
        function SPRButtonPushed(app, event)
            app.tabla.ColumnName={'T-1','T','INTERVALO'};
            app.tabla.Data=app.spr;
        end

        % Button pushed function: IQRSButton
        function IQRSButtonPushed(app, event)
            app.tabla.ColumnName={'T-1','T','INTERVALO'};
            app.tabla.Data=app.qrs;
        end

        % Button pushed function: IQTButton
        function IQTButtonPushed(app, event)
            app.tabla.ColumnName={'T-1','T','INTERVALO'};
            app.tabla.Data=app.qt;
        end

        % Button pushed function: OTButton
        function OTButtonPushed(app, event)
            app.tabla.ColumnName={'MÍNIMO','MÁXIMO','AMPLITUD'};
            app.tabla.Data=app.t;
        end

        % Button pushed function: OPButton
        function OPButtonPushed(app, event)
            app.tabla.ColumnName={'MÍNIMO','MÁXIMO','AMPLITUD'};
            app.tabla.Data=app.p;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %En caso de resetear los valores que aparecen por pantalla, se elimina los datos de la.
            app.tabla.Data=cell(0,3);
            app.tabla.ColumnName={'T-1','T','INTERVALO'};
        end

        % Clicked callback: guardar_historial
        function guardar_historialClicked(app, event)
            datosn1={' ', 'I.RR', ' ', ' ','O.T',' ',' ','O.P',' '};
            datos1=[app.rr app.t app.p];
            datos2=[app.it app.st app.sst app.pr app.spr app.qrs app.qt];
            datosn2={' ', 'I.T', ' ', ' ','I.ST',' ',' ','S.ST',' ',' ','I.PR',' ',' ','S.PR',' ',' ','I.QRS',' ',' ','I.QT',' '};
            datos_ondas=[datosn1;datos1];  
            xlswrite('datos_historial_ondas',datos_ondas)
            datos_intervalos=[datosn2;datos2]; 
            xlswrite('datos_historial_intervalos',datos_intervalos)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.guardar_historial = uipushtool(app.Toolbar);
            app.guardar_historial.ClickedCallback = createCallbackFcn(app, @guardar_historialClicked, true);
            app.guardar_historial.Icon = 'save.png';
            app.HISTORIALPanel = uipanel(app.UIFigure);
            app.HISTORIALPanel.Title = 'HISTORIAL';
            app.IRRButton = uibutton(app.HISTORIALPanel, 'push');
            app.IRRButton.ButtonPushedFcn = createCallbackFcn(app, @IRRButtonPushed, true);
            app.IRRButton.Text = 'I.RR';
            app.OPButton = uibutton(app.HISTORIALPanel, 'push');
            app.OPButton.ButtonPushedFcn = createCallbackFcn(app, @OPButtonPushed, true);
            app.OPButton.Text = 'O. P';
            app.ITButton = uibutton(app.HISTORIALPanel, 'push');
            app.ITButton.ButtonPushedFcn = createCallbackFcn(app, @ITButtonPushed, true);
            app.ITButton.Text = 'I. T';
            app.ISTButton = uibutton(app.HISTORIALPanel, 'push');
            app.ISTButton.ButtonPushedFcn = createCallbackFcn(app, @ISTButtonPushed, true);
            app.ISTButton.Text = 'I. ST';
            app.SSTButton = uibutton(app.HISTORIALPanel, 'push');
            app.SSTButton.ButtonPushedFcn = createCallbackFcn(app, @SSTButtonPushed, true);
            app.SSTButton.Text = 'S. ST';
            app.IPRButton = uibutton(app.HISTORIALPanel, 'push');
            app.IPRButton.ButtonPushedFcn = createCallbackFcn(app, @IPRButtonPushed, true);
            app.IPRButton.Text = 'I. PR';
            app.IQRSButton = uibutton(app.HISTORIALPanel, 'push');
            app.IQRSButton.ButtonPushedFcn = createCallbackFcn(app, @IQRSButtonPushed, true);
            app.IQRSButton.Text = 'I. QRS';
            app.IQTButton = uibutton(app.HISTORIALPanel, 'push');
            app.IQTButton.ButtonPushedFcn = createCallbackFcn(app, @IQTButtonPushed, true);
            app.IQTButton.Text = 'I. QT';
            app.SPRButton = uibutton(app.HISTORIALPanel, 'push');
            app.SPRButton.ButtonPushedFcn = createCallbackFcn(app, @SPRButtonPushed, true);
            app.SPRButton.Text = 'S. PR';
            app.OTButton = uibutton(app.HISTORIALPanel, 'push');
            app.OTButton.ButtonPushedFcn = createCallbackFcn(app, @OTButtonPushed, true);
            app.OTButton.Text = 'O. T';
            app.Button = uibutton(app.HISTORIALPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'reset.png';
            app.Button.Text = '';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'T-1'; 'T'; 'INTERVALO'};
            app.tabla.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v_historial(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end