classdef v_principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Toolbar    matlab.ui.container.Toolbar
        b_info     matlab.ui.container.toolbar.PushTool
        b_guardar  matlab.ui.container.toolbar.PushTool
        Label1     matlab.ui.control.Label
        b_on_off   matlab.ui.control.Button
        b_freeze   matlab.ui.control.Button
        b_reset    matlab.ui.control.Button
        b_filtro   matlab.ui.control.Button
        b_resul    matlab.ui.control.Button
        FFT        matlab.ui.control.Button
        Grafica    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %Se definen una serie de variables denominadas públicas, esto es
        %porque existe la necesidad de compartir ciertos valores entre
        %funciones que App Designer define como privadas.
        %-----------------------------------------------------------------%
        %Variables públicas:
        ecg          %vector donde se guarda la señal que se adquiere de Arduino
        ecg_filtrada %vector donde se guarda la señal filtrada a partir de los datos de la variable "tension" antes mencionada.
        tiempo       %vector del eje temporal en segundos. 
        n_muestras   %variable donde se almacena la longitud de nº de muestras.
        %------------%
        vfrec=[];    %vector del eje temporal en Hz para la gráfica de la FFT de "tension"
        vfft=[];     %vector que almacena el valor amplitud de la FFT de "tension"
        vfrec1=[];   %vector del eje temporal en Hz para la gráfica de la FFT de "ecg_filtrada"
        vfft1=[];    %vector que almacena el valor amplitud de la FFT de "ecg_filtrada"
        %------------%
        aux_bfiltro=0;%vbl que permite conocer si se ha pulsado o no el pulsador de filtro.
        aux2_bfiltro=0;%vbl que permite conocer si se ha pulsado por 1º o 2º vez el pulsador de filtro.
        %------------%
        bstop=0;     %vbl que permite conocer si se ha pulsado o no el pulsador de stop.
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: b_on_off
        function b_on_offButtonPushed(app, event)
%------------------------------------------------------------------------------------------%
            %Se comienza cerrando los puertos que estén abiertos para evitar que el puerto deseado esté abierto y genere un 
            %problema desde el incio de su ejecución.
            n=instrfind;    
            for i=1:size(n,2)
                fclose(n(i)); 
            end
            x=0;%vbl que va indicando el valor actual de eje OX en segundos
            color=[1 0 1];%color que adquiere "tensión" dentro de la gráfica
            app.Grafica.YLim=[-1.8 1.8]; 
            
            %Se ejecuata la función DAQ_Start, definiendo en su interior el puerto a abrir y su velocidad,
            %ya que, deberá coincidir con el valor definido en Arduino IDE para la lectura de muestras 
            %'COM3','BaudRate',230400. Se espera 2s para confirmar que se ha abierto de manera el puerto 
            %antes de recibir datos.
            DAQ_Start();
            pause(2)

            %La duración del ECG será durante 15s, es por ello que el bucle se ejecuta 10 veces, ya que,
            %cada vector nuevo de datos incluye 750 muestras por cada 2ms.
            %                750 x 2ms x 10 =15s
            %Se llama a la función grafica_noFPB, se envia "tension" para que se vaya actualizando dicho
            %vector.
            for i=1:1:10
            tic
            tiempo1=[];
                [tension]=grafica_noFPB();
                %Se va construyendo el eje temporal cada vez que se va
                %actualizando "tension"
                for k=1:1:750
                    x=x+0.002;
                    tiempo1=[tiempo1,x];
                end
                %La gráfica permite ver un margen de 2s., una vez sobrepasado este valor se va moviendo 
                %cada vez que se van visualizando nuevas muestras.
                if x<2  
                    xlim(app.Grafica,[0 2]);   
                else
                    xlim(app.Grafica,[x-2 x]);
                end
            app.ecg=[app.ecg,tension];
            app.tiempo=[app.tiempo,tiempo1];
            %Representación de la señal "tension"                
            hold(app.Grafica, 'on')
                plot(app.Grafica,tiempo1,tension,'LineWidth',2,'Color',color)
                legend(app.Grafica,'Señal NO filtrada')
                toc
            pause(2-toc)           
            %Una vez se haya actualizado la gráfica con los nuevos valores,se analiza si el usuario pulsó
            %o no el botón de stop.
                if app.bstop==1
                    app.bstop=0;
                    n=instrfind;    
                    for j=1:size(n,2)
                        fclose(n(j)); 
                    end
                    break;
                end
            end
            app.n_muestras=length(app.ecg);
            app.aux_bfiltro=1;
%------------------------------------------------------------------------------------------%
        end

        % Button pushed function: FFT
        function FFTButtonPushed(app, event)
            %Se realiza la FFT de la señal no filtrada
            %dato_arduino=dato_arduino/max(abs(dato_arduino));
            app.vfft=fft(app.ecg);
            app.vfft=abs(app.vfft);
            %NOTA: Una vez se simula el espectro de frecuencias, se puede
            %apreciar que es proporcional, por tanto se trabaja con  la mitad
            %del espectro frecuencial
            app.vfft=app.vfft(1:round(end/2));  
            app.vfft=app.vfft/max(abs(app.vfft));
            tam_fft=length(app.vfft);
            app.vfrec=(1:1:tam_fft)*(((1/0.002)/2)/tam_fft); %Frecuencia Nyquist= fs/2

            %Al pulsar el botón de FFT, se abrirá una ventana auxiliar denominada v_fft, para conocer que 
            %señales tiene que representar aux_bfiltro, aux2_bfiltro que indican si se pulsó o no el botón 
            %de filtro y cuantas veces ha sido pulsado, a mayores de los valores que harán faltan para 
            %realizar la gráfica FFT.
            %% Variables necesarias para la FFT
            v_fft(app.aux_bfiltro,app.aux2_bfiltro,app.vfrec,app.vfft,app.vfrec1,app.vfft1);            
        end

        % Clicked callback: b_info
        function b_infoClicked(app, event)
            %Se abre una ventana axuliar denominado v_info donde se visuliazrá una imagen indicado cómo se 
            %colocan los electrodos.
            v_info
        end

        % Button pushed function: b_resul
        function b_resulButtonPushed(app, event)
            %Se realiza los cálculos de los parámetros para ello será necesario que valores se desean ver 
            %si de la señal "ecg_filtrada" o "tension". Será necesio compartir una serie de variables con 
            % la ventana auxiliar v_resultados.
            if (app.aux_bfiltro==1 && app.aux2_bfiltro==0)
                v_resultados(app.ecg,app.tiempo,app.n_muestras);  
            elseif (app.aux_bfiltro==0 && app.aux2_bfiltro==0)
                v_resultados(app.ecg_filtrada,app.tiempo,app.n_muestras);
            end
        end

        % Button pushed function: b_filtro
        function b_filtroButtonPushed(app, event)
        app.aux_bfiltro=0;
        %Se llama a la funcion grafica_FPB,  en ella, se aplica el filtro
        %indicado y se obtienen 3 salidas.
        [app.ecg_filtrada,app.vfrec1,app.vfft1]=grafica_FPB(app.ecg);
        %Se distinguen 2 colores diferentes para representan las 2 señales disponibles.
        color=[1 0 1];
        color2=[0 0.45 0.74];
        if app.aux2_bfiltro==0
            cla(app.Grafica)
            hold(app.Grafica, 'on')  
            plot(app.Grafica,app.tiempo,app.ecg,'LineWidth',2,'Color',color,'DisplayName','Señal NO filtrada')
            plot(app.Grafica,app.tiempo,app.ecg_filtrada,'LineWidth',2,'Color',color2,'DisplayName','Señal Filtrada')
            legend(app.Grafica)
            hold(app.Grafica, 'off')  
            app.aux2_bfiltro=1;
        elseif app.aux2_bfiltro==1
            cla(app.Grafica)
            plot(app.Grafica,app.tiempo,app.ecg_filtrada,'LineWidth',2,'Color',color,'DisplayName','Señal Filtrada')
            legend(app.Grafica)
            app.aux2_bfiltro=0;
        end
        end

        % Button pushed function: b_reset
        function b_resetButtonPushed(app, event)
             %Se resetean la gráfica y las variables para volver a empezar un nuevo ECG.
             cla(app.Grafica)
             xlim(app.Grafica,[0 2]);
             delete(legend(app.Grafica))
             app.ecg=[];
             app.ecg_filtrada=[];
             app.tiempo=[];
             app.n_muestras=0;
             app.vfrec=[];
             app.vfft=[];
             app.vfrec1=[];
             app.vfft1=[];            
        end

        % Button pushed function: b_freeze
        function b_freezeButtonPushed(app, event)
            app.bstop=1;
        end

        % Clicked callback: b_guardar
        function b_guardarClicked(app, event)
            saveas(app.Grafica,uiputfile({'*.fig'}))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.b_info = uipushtool(app.Toolbar);
            app.b_info.ClickedCallback = createCallbackFcn(app, @b_infoClicked, true);
            app.b_info.Icon = 'info.png';
            app.b_guardar = uipushtool(app.Toolbar);
            app.b_guardar.ClickedCallback = createCallbackFcn(app, @b_guardarClicked, true);
            app.b_guardar.Icon = 'save.png';
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = '';
            app.b_on_off = uibutton(app.UIFigure, 'push');
            app.b_on_off.ButtonPushedFcn = createCallbackFcn(app, @b_on_offButtonPushed, true);
            app.b_on_off.Icon = 'on_off.png';
            app.b_on_off.Text = '';
            app.b_freeze = uibutton(app.UIFigure, 'push');
            app.b_freeze.ButtonPushedFcn = createCallbackFcn(app, @b_freezeButtonPushed, true);
            app.b_freeze.Icon = 'freeze.png';
            app.b_freeze.Text = '';
            app.b_reset = uibutton(app.UIFigure, 'push');
            app.b_reset.ButtonPushedFcn = createCallbackFcn(app, @b_resetButtonPushed, true);
            app.b_reset.Icon = 'reset.png';
            app.b_reset.Text = '';
            app.b_filtro = uibutton(app.UIFigure, 'push');
            app.b_filtro.ButtonPushedFcn = createCallbackFcn(app, @b_filtroButtonPushed, true);
            app.b_filtro.Icon = 'filter_p.png';
            app.b_filtro.Text = '';
            app.b_resul = uibutton(app.UIFigure, 'push');
            app.b_resul.ButtonPushedFcn = createCallbackFcn(app, @b_resulButtonPushed, true);
            app.b_resul.Icon = 'resultados.png';
            app.b_resul.Text = '';
            app.FFT = uibutton(app.UIFigure, 'push');
            app.FFT.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFT.Icon = 'fft.png';
            app.FFT.Text = '';
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'ELECTROCARDIOGRAMA')
            xlabel(app.Grafica, 'TIEMPO (s)')
            ylabel(app.Grafica, 'TENSIÓN')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v_principal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end