classdef v_resultados < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Toolbar             matlab.ui.container.Toolbar
        guardar_media       matlab.ui.container.toolbar.PushTool
        RESULTADOSPanel     matlab.ui.container.Panel
        HRLabel             matlab.ui.control.Label
        HRlpm               matlab.ui.control.NumericEditField
        IRREditFieldLabel   matlab.ui.control.Label
        IRR                 matlab.ui.control.NumericEditField
        ITEditFieldLabel    matlab.ui.control.Label
        IT                  matlab.ui.control.NumericEditField
        ISTEditFieldLabel   matlab.ui.control.Label
        IST                 matlab.ui.control.NumericEditField
        SSTEditFieldLabel   matlab.ui.control.Label
        SST                 matlab.ui.control.NumericEditField
        IPREditFieldLabel   matlab.ui.control.Label
        IPR                 matlab.ui.control.NumericEditField
        SPREditFieldLabel   matlab.ui.control.Label
        SPR                 matlab.ui.control.NumericEditField
        IQRSEditFieldLabel  matlab.ui.control.Label
        IQRS                matlab.ui.control.NumericEditField
        IQTEditFieldLabel   matlab.ui.control.Label
        IQT                 matlab.ui.control.NumericEditField
        LPMLabel            matlab.ui.control.Label
        msLabel             matlab.ui.control.Label
        msLabel_3           matlab.ui.control.Label
        msLabel_4           matlab.ui.control.Label
        msLabel_5           matlab.ui.control.Label
        msLabel_6           matlab.ui.control.Label
        msLabel_7           matlab.ui.control.Label
        msLabel_8           matlab.ui.control.Label
        msLabel_2           matlab.ui.control.Label
        TLabel              matlab.ui.control.Label
        O_T                 matlab.ui.control.NumericEditField
        PLabel              matlab.ui.control.Label
        O_P                 matlab.ui.control.NumericEditField
        mVLabel             matlab.ui.control.Label
        mVLabel_2           matlab.ui.control.Label
        b_historial         matlab.ui.control.Button
    end

    
    properties (Access = public)
        %Se definen los siguientes vectores de datos públicos, ya que en ellos se van a a guardar los datos
        %necesarios que se visualizan en la ventana auxiliar v_historial.
        datosRR=[];
        datosIT=[];
        datosST=[];
        datosSST=[];
        datosPR=[];
        datosSPR=[];
        datosQRS=[];
        datosQT=[];
        datosT=[];
        datosP=[];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tension, tiempo, n_muestras)
            %Se hallan los puntos de interés siguiendo al orden que aparece a continuación, llamando a la a la función detectar_valores() tantas 
            % veces como sea necesario, en este caso se construye un bucle.
            %--------------------------------------------------------------------------------------------------------------------------------------%
            %VARIABLES DE ENTRADA, se tratan de vectores que contienen los valores para cada caso
            %Tipo, define el caso a analizar.
            %Umbral, que será utilizada para fijar un valor mínimo que debe alcanzar un punto hallado para considerarlo como válido.
            %dim, determina la longitud de nº de muestras que se debe analizar.
            %min, índice que indica a partir de que posición del vector se debe empezar la búsqueda del punto/onda deseada.
            %posiciones, intervalo que acota la búsqueda de puntos/ondas.
            %incremento, indica si la búsqueda debe ser decreciente o creciente a partir del valor inicial que se indica.
            %max2, indica hasta donde debe buscar el punto/onda dentro de un intervalo determinado
            %--------------------------------------------------------------------------------------------------------------------------------------%
            %VARIABLES DE SALIDA, se guarda dentro del vector puntos
            %puntos_xxx, almacena los valores en tensión
            %puntosx_tiempo/tiemposxxx_xxx, almacena los valores en tiempo de la muestra guardada
            %posiciones_xxx, almacena la posición de la muestra en el vector "tension"
            %------------------------------------------------------INICIO DETECCIÓN DE ONDAS-------------------------------------------------------%            
            %DETECTAR ONDA R
            dim_ecg_filtro=length(tension);
            pico_max=max(tension);
            umbral=0.35*pico_max;
            tipo=0;  
            [puntos_R,puntosR_tiempo,posiciones_R]=detectar_valores(tipo,tension,tiempo,umbral,dim_ecg_filtro,1,0,1,0);
            dim_R=length(posiciones_R);
            dim_onda=dim_R-1;
            dim_punto=dim_R-2;        
            %---------------------------------------------------------DEFINICIÓN VBLS---------------------------------------------------------------%
            puntos_Q=zeros(1,dim_onda);
            puntos_QI=zeros(1,dim_punto);
            puntos_S=zeros(1,dim_onda);
            puntos_SS=zeros(1,dim_punto);
            puntos_T=zeros(1,dim_onda);
            puntos_TI=zeros(1,dim_punto);
            puntos_TS=zeros(1,dim_punto);
            puntos_P=zeros(1,dim_onda);
            puntos_PI=zeros(1,dim_punto);
            puntos_PS=zeros(1,dim_punto);
            
            puntosQ_tiempo=zeros(1,dim_onda);
            tiemposQ_inicio=zeros(1,dim_punto);
            puntosS_tiempo=zeros(1,dim_onda);
            tiemposS_stop=zeros(1,dim_punto);
            puntosT_tiempo=zeros(1,dim_onda);
            tiemposT_inicio=zeros(1,dim_punto);
            tiemposT_stop=zeros(1,dim_punto);
            puntosP_tiempo=zeros(1,dim_onda);
            tiemposP_inicio=zeros(1,dim_punto);
            tiemposP_stop=zeros(1,dim_punto);
            
            posiciones_Q=zeros(1,dim_onda);
            posiciones_QI=zeros(1,dim_punto);
            posiciones_S=zeros(1,dim_onda);
            posiciones_SS=zeros(1,dim_punto);
            posiciones_T=zeros(1,dim_onda);
            posiciones_TI=zeros(1,dim_punto);
            posiciones_TS=zeros(1,dim_punto);
            posiciones_P=zeros(1,dim_onda);
            posiciones_PI=zeros(1,dim_punto);
            posiciones_PS=zeros(1,dim_punto);
            
            puntos=[{puntos_Q,puntos_QI,puntos_S,puntos_SS,puntos_T,puntos_TI,puntos_TS,puntos_P,puntos_PI,puntos_PS};
            {puntosQ_tiempo,tiemposQ_inicio,puntosS_tiempo,tiemposS_stop,puntosT_tiempo,tiemposT_inicio,tiemposT_stop,puntosP_tiempo,tiemposP_inicio,tiemposP_stop};
            {posiciones_Q,posiciones_QI,posiciones_S,posiciones_SS,posiciones_T,posiciones_TI,posiciones_TS,posiciones_P,posiciones_PI,posiciones_PS}];
            
            tipo=[1,2,1,2,3,4,4,3,4,4];
            umbralTIS=0;
            umbralPIS=0;
            umbral={0,0,0,0,0,umbralTIS,umbralTIS,0,umbralPIS,umbralPIS};
            dim={dim_R,dim_onda,dim_onda,dim_onda,puntos{3,1},dim_onda,dim_onda,puntos{3,1},dim_onda,dim_onda};
            min={2,1,1,2,puntos{3,3},1,1,puntos{3,7},1,1};
            posiciones={posiciones_R,puntos{3,1},posiciones_R,puntos{3,3},puntos{3,3},puntos{3,5},puntos{3,5},0,puntos{3,8},puntos{3,8}};
            incremento=[-1,-1,1,1,1,-1,1,1,-1,1];
            max2=[0,0,0,n_muestras,dim_onda,0,n_muestras,dim_onda,0,n_muestras];
            %Detección de : onda Q, inicio onda Q, onda S, fin onda S, onda T, inicio onda T, fin onda T, onda P, inicio onda P, fin onda P
                for i=1:1:10
                    [puntos{1,i},puntos{2,i},puntos{3,i}]=detectar_valores(tipo(i),tension,tiempo,umbral{1,i},dim{1,i},min{1,i},posiciones{1,i},incremento(i),max2(i));
                    if i==5
                        umbralTIS=0.01*(puntos{1,5});
                    elseif i==8
                        umbralPIS=0.01*(puntos{1,8});
                    end
                    umbral={0,0,0,0,0,umbralTIS,umbralTIS,0,umbralPIS,umbralPIS};
                    posiciones={posiciones_R,puntos{3,1},posiciones_R,puntos{3,3},puntos{3,3},puntos{3,5},puntos{3,5},0,puntos{3,8},puntos{3,8}};
                    min={2,1,1,2,puntos{3,3},1,1,puntos{3,7},1,1};
                    dim={dim_R,dim_onda,dim_onda,dim_onda,puntos{3,1},dim_onda,dim_onda,puntos{3,1},dim_onda,dim_onda};
                    
                end           
            %---------------------------------------------------------FIN DETECCIÓN DE ONDAS-------------------------------------------------------%
            %----------------------------------------------------------INICIO CÁLCULO MEDIO--------------------------------------------------------%
            % Cálculo de los parámetros a partir de los datos obtenidos anteriormente , obteniendo el valor medio de cada uno de ellos.
            %Para realizar el cálculo medio de los parámetros se hace uso de la función calculo_valor_medio(), y se llama varias veces, por ello se 
            %utiliza un bucle para su llamada continua.
            %--------------------------------------------------------------------------------------------------------------------------------------%
            %VARIABLE DE ENTRADA
            %valores_Max, valores que indican el valor máximo del intervalo.
            %valores_Min, valores que indican el valor mínimo del intervalo.
            %maximos, indica el nº de intervalos a calcular
            %aux, que toma un valor diferente cada vez que se realiza un cálculo.
            %--------------------------------------------------------------------------------------------------------------------------------------%
            valores_Max={puntosR_tiempo,puntos{2,7},puntos{2,7},puntos{2,6},puntos{2,2},puntos{2,2},puntos{1,5},puntos{1,8},puntos{2,4},puntos{2,4}};
            valores_Min={0,puntos{2,6},puntos{2,4},puntos{2,4},puntos{2,9},puntos{2,10},puntos{1,6},puntos{1,9},puntos{2,2},puntos{2,2}};
            maximos=[dim_R,dim_onda,dim_punto,dim_punto,dim_punto,dim_punto,dim_onda,dim_onda,dim_punto,dim_punto];
            aux=[0,1,2,2,2,2,2,2,2,2];
            % Cálculo de intervalos, y anchos de onda. Representado en ms o mV
                for i=1:1:10
                    [salidas(i),datos_historial{1,i}]=calculo_valor_medio(valores_Max{1,i},valores_Min{1,i},maximos(i),aux(i));
                    if i==2 
                        auxiliar1=puntos{2,7};
                        auxiliar1=auxiliar1(2:dim_onda);
                        puntos{2,7}=auxiliar1;
            
                        auxiliar2=puntos{2,6};
                        auxiliar2=auxiliar2(2:dim_onda);
                        puntos{2,6}=auxiliar2;
                        valores_Max={puntosR_tiempo,puntos{2,7},puntos{2,7},puntos{2,6},puntos{2,2},puntos{2,2},puntos{1,5},puntos{1,8},puntos{2,4},puntos{2,7}};
                        valores_Min={0,puntos{2,6},puntos{2,4},puntos{2,4},puntos{2,10},puntos{2,9},puntos{1,6},puntos{1,9},puntos{2,2},puntos{2,2}};
                    end
                end
            %----------------------------------------------------------- VALOR MEDIO ---------------------------------------------------------------%
                app.IRR.Value=salidas(1);
                app.IT.Value=salidas(2);
                app.IST.Value=salidas(3);
                app.SST.Value=salidas(4);
                app.IPR.Value=salidas(5);
                app.SPR.Value=salidas(6);
                app.O_T.Value=salidas(7);
                app.O_P.Value=salidas(8);
                app.IQRS.Value=salidas(9);
                app.IQT.Value=salidas(10);
                tiempo_lectura=tiempo(n_muestras);
                app.HRlpm.Value=round((60/tiempo_lectura)*dim_R); %nº de latidos por minuto  
            %---------------------------------------------------------FIN CÁLCULO MEDIO-------------------------------------------------------------%
            %--------------------------------------------------------- DATOS HISTORIAL -------------------------------------------------------------%         
                app.datosRR=datos_historial{1,1};
                app.datosIT=datos_historial{1,2};
                app.datosST=datos_historial{1,3};
                app.datosSST=datos_historial{1,4};
                app.datosPR=datos_historial{1,5};
                app.datosSPR=datos_historial{1,6};
                app.datosT=datos_historial{1,7};
                app.datosP=datos_historial{1,8};
                app.datosQRS=datos_historial{1,9};
                app.datosQT=datos_historial{1,10};            
         
        end

        % Button pushed function: b_historial
        function b_historialButtonPushed(app, event)
            v_historial(app.datosRR,app.datosIT,app.datosST,app.datosSST,app.datosPR,app.datosSPR,app.datosT,app.datosP,app.datosQRS,app.datosQT)
        end

        % Clicked callback: guardar_media
        function guardar_mediaClicked(app, event)
            datos_medio_excel= {'HR','I.RR', 'I.T', 'I.ST', 'S.ST','I.PR','S.PR','O.T','O.P','I.QRS','I.QT'; 
            app.HRlpm.Value,app.IRR.Value, app.IT.Value, app.IST.Value, app.SST.Value,app.IPR.Value,app.SPR.Value,app.O_T.Value,app.O_P.Value,app.IQRS.Value,app.IQT.Value};
            xlswrite('datos_medio',datos_medio_excel)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.guardar_media = uipushtool(app.Toolbar);
            app.guardar_media.ClickedCallback = createCallbackFcn(app, @guardar_mediaClicked, true);
            app.guardar_media.Icon = 'save.png';
            app.RESULTADOSPanel = uipanel(app.UIFigure);
            app.RESULTADOSPanel.Title = 'RESULTADOS';
            app.HRLabel = uilabel(app.RESULTADOSPanel);
            app.HRLabel.Text = 'HR';
            app.HRlpm = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.IRREditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.IRREditFieldLabel.Text = 'I. RR';
            app.IRR = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.ITEditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.ITEditFieldLabel.Text = 'I. T';
            app.IT = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.ISTEditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.ISTEditFieldLabel.Text = 'I. ST';
            app.IST = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.SSTEditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.SSTEditFieldLabel.Text = 'S. ST';
            app.SST = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.IPREditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.IPREditFieldLabel.Text = 'I. PR';
            app.IPR = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.SPREditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.SPREditFieldLabel.Text = 'S. PR';
            app.SPR = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.IQRSEditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.IQRSEditFieldLabel.Text = 'I. QRS';
            app.IQRS = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.IQTEditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.IQTEditFieldLabel.Text = 'I. QT';
            app.IQT = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.LPMLabel = uilabel(app.RESULTADOSPanel);
            app.LPMLabel.Text = 'LPM';
            app.msLabel = uilabel(app.RESULTADOSPanel);
            app.msLabel.Text = 'ms';
            app.msLabel_3 = uilabel(app.RESULTADOSPanel);
            app.msLabel_3.Text = 'ms';
            app.msLabel_4 = uilabel(app.RESULTADOSPanel);
            app.msLabel_4.Text = 'ms';
            app.msLabel_5 = uilabel(app.RESULTADOSPanel);
            app.msLabel_5.Text = 'ms';
            app.msLabel_6 = uilabel(app.RESULTADOSPanel);
            app.msLabel_6.Text = 'ms';
            app.msLabel_7 = uilabel(app.RESULTADOSPanel);
            app.msLabel_7.Text = 'ms';
            app.msLabel_8 = uilabel(app.RESULTADOSPanel);
            app.msLabel_8.Text = 'ms';
            app.msLabel_2 = uilabel(app.RESULTADOSPanel);
            app.msLabel_2.Text = 'ms';
            app.TLabel = uilabel(app.RESULTADOSPanel);
            app.TLabel.Text = 'T';
            app.O_T = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.PLabel = uilabel(app.RESULTADOSPanel);
            app.PLabel.Text = 'P';
            app.O_P = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.mVLabel = uilabel(app.RESULTADOSPanel);
            app.mVLabel.Text = 'mV';
            app.mVLabel_2 = uilabel(app.RESULTADOSPanel);
            app.mVLabel_2.Text = 'mV';
            app.b_historial = uibutton(app.RESULTADOSPanel, 'push');
            app.b_historial.ButtonPushedFcn = createCallbackFcn(app, @b_historialButtonPushed, true);
            app.b_historial.Icon = 'historial.png';
            app.b_historial.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v_resultados(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end